classdef Sistema_Binaural_TEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AzSliderLabel             matlab.ui.control.Label
        AzSlider                  matlab.ui.control.Slider
        ElSliderLabel             matlab.ui.control.Label
        ElSlider                  matlab.ui.control.Slider
        SISTEMABIAURALLabel       matlab.ui.control.Label
        CalificarsensacindemolestiaLabel  matlab.ui.control.Label
        HistogramaLabel           matlab.ui.control.Label
        CLICKButton_2             matlab.ui.control.Button
        CLICKButton_3             matlab.ui.control.Button
        GundistanciamSliderLabel  matlab.ui.control.Label
        GundistanciamSlider       matlab.ui.control.Slider
        TERAPIADEENTRENAMIENTOPARAESPECTROAUTISTALabel  matlab.ui.control.Label
        STARTButton               matlab.ui.control.Button
    end

    properties (Access = private)
        Az = 1
        El = 1
        dis = 0.031623
        int = 0
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changing function: ElSlider
        function ElSliderValueChanging(app, event)
            changingValue = event.Value;
            app.El = changingValue;            
        end

        % Value changing function: AzSlider
        function AzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Az = changingValue;
        end

        % Value changing function: GundistanciamSlider
        function DistanciaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.dis = changingValue;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %% Cargamos base de datos HRTF
            load 'ReferenceHRTF.mat' hrtfData sourcePosition
            
            %% Acomodamos datos a la función interpolatedHRTF
            hrtfData = permute(double(hrtfData),[2,3,1]);
            sourcePosition = sourcePosition(:,[1,2]);
            
            %% Formamos las posiciones con los ángulos de Azimut y Elevación --> EL USUARIO 
            %% ELIGE ESTAS POSICIONES
            global desiredAz desiredEl;
            global distancia;

            desiredAz = app.Az;
            desiredEl = app.El; 
            
            distancia = app.dis;
            
            desiredPosition = [desiredAz desiredEl];
            
            %% Procesamos 
            interpolatedIR  = interpolateHRTF(hrtfData,sourcePosition,desiredPosition, 'Algorithm','VBAP');
            
            %% Determinamos los datos procesados para canal L y R respectivamente
            leftIR = squeeze(interpolatedIR(:,1,:))';
            rightIR = squeeze(interpolatedIR(:,2,:))';
            
            %% Creamos buffers (constructores) de lectura y escritura de audio
            fileReader = dsp.AudioFileReader('supporters.wav','SamplesPerFrame',44100);
            deviceWriter = audioDeviceWriter('SampleRate',fileReader.SampleRate);
            
            %% Creamos filtros FIR
            leftFilter = dsp.FIRFilter('Numerator',leftIR);
            rightFilter = dsp.FIRFilter('Numerator',rightIR);
            
            %% Iniciamos datos necesarios para la espacialización en loop
            durationPerPosition = 1;
            samplesPerPosition = durationPerPosition*fileReader.SampleRate;
            samplesPerPosition = samplesPerPosition - rem(samplesPerPosition,fileReader.SamplesPerFrame);
            
            sourcePositionIndex = 1;
            samplesRead = 0;
            
            %% Loop espacializador
            while ~isDone(fileReader)
                
                %% Creamos objeto del audio a espacializar
                audioIn = distancia.*fileReader();
            
                %% Stereo to Mono
                audioIn = audioIn(:,1);
            
                %% Cambio de muestras del audio In que se van espacializando
                samplesRead = samplesRead + fileReader.SamplesPerFrame;
                
                %% Filtrado
                leftChannel = leftFilter(audioIn(:,1));
                rightChannel = rightFilter(audioIn(:,1));
                
                %% Reproducción L/R
                out = [leftChannel,rightChannel];
                deviceWriter(out);
                
                %% Cambios de posición
                if mod(samplesRead,samplesPerPosition) == 0
                    sourcePositionIndex = sourcePositionIndex + 1;
                end
            end
            
            release(fileReader)
            
            release(deviceWriter) 
        end

        % Button pushed function: CLICKButton_3
        function CLICKButton_3Pushed(app, event)
            Sistema_Binaural_TEA_app2
            app.int = app.int +1; 
        end

        % Button pushed function: CLICKButton_2
        function CLICKButton_2Pushed(app, event)
            global x;
            x = app.int;
            
            load molestia.mat sensa;
            
            global y;
            y = sensa;
            
            bar(x,y);
            yticks([1 2 3 4 5])
            xlabel('Número de intentos')
            ylabel('Sensación de molestia')

                        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AzSliderLabel = uilabel(app.UIFigure);
            app.AzSliderLabel.Text = 'Az';
            app.AzSlider = uislider(app.UIFigure);
            app.AzSlider.Limits = [0 360];
            app.AzSlider.ValueChangingFcn = createCallbackFcn(app, @AzSliderValueChanging, true);
            app.AzSlider.Tooltip = {''};
            app.ElSliderLabel = uilabel(app.UIFigure);
            app.ElSliderLabel.Text = 'El';
            app.ElSlider = uislider(app.UIFigure);
            app.ElSlider.Limits = [-90 90];
            app.ElSlider.ValueChangingFcn = createCallbackFcn(app, @ElSliderValueChanging, true);
            app.SISTEMABIAURALLabel = uilabel(app.UIFigure);
            app.SISTEMABIAURALLabel.Text = 'SISTEMA BIAURAL';
            app.CalificarsensacindemolestiaLabel = uilabel(app.UIFigure);
            app.CalificarsensacindemolestiaLabel.Text = 'Calificar sensación de molestia:';
            app.HistogramaLabel = uilabel(app.UIFigure);
            app.HistogramaLabel.Text = 'Histograma:';
            app.CLICKButton_2 = uibutton(app.UIFigure, 'push');
            app.CLICKButton_2.ButtonPushedFcn = createCallbackFcn(app, @CLICKButton_2Pushed, true);
            app.CLICKButton_2.Text = {'CLICK'; ''};
            app.CLICKButton_3 = uibutton(app.UIFigure, 'push');
            app.CLICKButton_3.ButtonPushedFcn = createCallbackFcn(app, @CLICKButton_3Pushed, true);
            app.CLICKButton_3.Text = {'CLICK'; ''};
            app.GundistanciamSliderLabel = uilabel(app.UIFigure);
            app.GundistanciamSliderLabel.Text = 'G (un)/distancia (m)';
            app.GundistanciamSlider = uislider(app.UIFigure);
            app.GundistanciamSlider.Limits = [0.031623 1];
            app.GundistanciamSlider.MajorTicks = [0.031623 0.063096 0.125893 0.251189 0.501187 1];
            app.GundistanciamSlider.MajorTickLabels = {'32m', ' ', ' ', ' ', ' ', ' 1m'};
            app.GundistanciamSlider.ValueChangingFcn = createCallbackFcn(app, @DistanciaSliderValueChanging, true);
            app.GundistanciamSlider.MinorTicks = [];
            app.GundistanciamSlider.Tooltip = {''};
            app.GundistanciamSlider.Value = 0.031623;
            app.TERAPIADEENTRENAMIENTOPARAESPECTROAUTISTALabel = uilabel(app.UIFigure);
            app.TERAPIADEENTRENAMIENTOPARAESPECTROAUTISTALabel.Text = 'TERAPIA DE ENTRENAMIENTO PARA ESPECTRO AUTISTA';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sistema_Binaural_TEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end