classdef Sistema_Binaural_TEA_app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Calificadel1al5lasensacindemolestiaLabel  matlab.ui.control.Label
        Button    matlab.ui.control.Button
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
        Button_4  matlab.ui.control.Button
        Button_5  matlab.ui.control.Button
    end

    properties (Access = public)
        moles = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.moles = 1;
            sensa = app.moles;
            save('molestia','sensa');
            delete(app)

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.moles = 2;
            sensa = app.moles;
            save('molestia','sensa');
            delete(app)

        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.moles = 3;
            sensa = app.moles;
            save('molestia','sensa');
            delete(app)

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.moles = 4;
            sensa = app.moles;
            save('molestia','sensa');
            delete(app)

        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.moles = 5;
            sensa = app.moles;
            save('molestia','sensa');
            delete(app)


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Calificadel1al5lasensacindemolestiaLabel = uilabel(app.UIFigure);
            app.Calificadel1al5lasensacindemolestiaLabel.Text = 'Califica, del 1 al 5, la sensación de molestia:';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sistema_Binaural_TEA_app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end